classdef transformador_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AjudaButton                  matlab.ui.control.Button
        CheckBoxGraficos             matlab.ui.control.CheckBox
        EnsaiodeTranformadoresLabel  matlab.ui.control.Label
        TypeButtonGroup              matlab.ui.container.ButtonGroup
        MonofsicoButton              matlab.ui.control.RadioButton
        TrifsicoButton               matlab.ui.control.RadioButton
        CalcularButton               matlab.ui.control.Button
        SNEditField                  matlab.ui.control.NumericEditField
        S_NKVALabel                  matlab.ui.control.Label
        VNATEditField                matlab.ui.control.NumericEditField
        V_NATKVLabel                 matlab.ui.control.Label
        VNBTEditField                matlab.ui.control.NumericEditField
        V_NBTVLabel                  matlab.ui.control.Label
        V0EditField                  matlab.ui.control.NumericEditField
        V_0VLabel                    matlab.ui.control.Label
        I0EditField                  matlab.ui.control.NumericEditField
        I_0ALabel                    matlab.ui.control.Label
        P0EditField                  matlab.ui.control.NumericEditField
        P_0WLabel                    matlab.ui.control.Label
        VccEditField                 matlab.ui.control.NumericEditField
        V_CCVLabel                   matlab.ui.control.Label
        IccEditField                 matlab.ui.control.NumericEditField
        I_ccALabel                   matlab.ui.control.Label
        PCCEditField                 matlab.ui.control.NumericEditField
        P_CCWLabel                   matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        FigurasTab                   matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Circuitosimplificado2Label   matlab.ui.control.Label
        CircuitocompletoLabel        matlab.ui.control.Label
        CircuitoSimplificado2Label   matlab.ui.control.Label
        Figura1Tab                   matlab.ui.container.Tab
        Figura2Tab                   matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        fig2_1                       matlab.ui.control.Label
        fig2_2                       matlab.ui.control.Label
        fig2_3                       matlab.ui.control.Label
        fig2_4                       matlab.ui.control.Label
        fig2_5                       matlab.ui.control.Label
        fig2_6                       matlab.ui.control.Label
        fig2_7                       matlab.ui.control.Label
        fig2_8                       matlab.ui.control.Label
        fig2_9                       matlab.ui.control.Label
        fig2_10                      matlab.ui.control.Label
        fig2_11                      matlab.ui.control.Label
        fig2_12                      matlab.ui.control.Label
        Figura3Tab                   matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        fig3_1                       matlab.ui.control.Label
        fig3_2                       matlab.ui.control.Label
        fig3_3                       matlab.ui.control.Label
        fig3_4                       matlab.ui.control.Label
        fig3_5                       matlab.ui.control.Label
        fig3_6                       matlab.ui.control.Label
        RendimentoTab                matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Rend080                      matlab.ui.control.Label
        Comp080                      matlab.ui.control.Label
        Rend085                      matlab.ui.control.Label
        Comp085                      matlab.ui.control.Label
        Rend090                      matlab.ui.control.Label
        Comp090                      matlab.ui.control.Label
        Rend095                      matlab.ui.control.Label
        Comp095                      matlab.ui.control.Label
        Comp100                      matlab.ui.control.Label
        Rend100                      matlab.ui.control.Label
        Carregamento                 matlab.ui.control.Label
        SalvarCheckBox               matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%INPUT%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.S_NKVALabel.Interpreter  = 'latex'
            app.V_NATKVLabel.Interpreter  = 'latex'
            app.V_NBTVLabel.Interpreter  = 'latex'
            app.V_0VLabel.Interpreter  = 'latex'
            app.I_0ALabel.Interpreter  = 'latex'
            app.P_0WLabel.Interpreter  = 'latex'
            app.V_CCVLabel.Interpreter  = 'latex'
            app.I_ccALabel.Interpreter  = 'latex'
            app.P_CCWLabel.Interpreter  = 'latex'
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OUTPUT%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Fugura 2
            app.fig2_1.Interpreter = 'latex'
            app.fig2_2.Interpreter = 'latex'
            app.fig2_3.Interpreter = 'latex'
            app.fig2_4.Interpreter = 'latex'
            app.fig2_5.Interpreter = 'latex'
            app.fig2_6.Interpreter = 'latex'
            app.fig2_7.Interpreter = 'latex'
            app.fig2_8.Interpreter = 'latex'
            app.fig2_9.Interpreter = 'latex'
            app.fig2_10.Interpreter = 'latex'
            app.fig2_11.Interpreter = 'latex'
            app.fig2_12.Interpreter = 'latex'
            % Figura 3
            app.fig3_1.Interpreter = 'latex'
            app.fig3_2.Interpreter = 'latex'
            app.fig3_3.Interpreter = 'latex'
            app.fig3_4.Interpreter = 'latex'
            app.fig3_5.Interpreter = 'latex'
            app.fig3_6.Interpreter = 'latex'
            % Rendimento Latex
            app.Carregamento.Interpreter  = 'latex'
            app.Rend080.Interpreter  = 'latex'
            app.Rend085.Interpreter  = 'latex'
            app.Rend090.Interpreter  = 'latex'
            app.Rend095.Interpreter  = 'latex'
            app.Rend100.Interpreter  = 'latex'
            app.Comp080.Interpreter  = 'latex'
            app.Comp085.Interpreter  = 'latex'
            app.Comp090.Interpreter  = 'latex'
            app.Comp095.Interpreter  = 'latex'
            app.Comp100.Interpreter  = 'latex'
        end

        % Button pushed function: AjudaButton
        function AjudaButtonPushed(app, event)
            web("https://github.com/emanuel-alves/Trabalho_EELI16")
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            close all;
            switch app.TypeButtonGroup.SelectedObject.Text
                case 'Monofásico'
                    
                     [fig1, fig2, fig3, IMAX, RendMPC, CompRend] = tranformador_monofasico( ...
                        app.SNEditField.Value, ...
                        app.VNATEditField.Value, ...
                        app.VNBTEditField.Value, ...
                        app.V0EditField.Value, ...
                        app.I0EditField.Value, ...
                        app.P0EditField.Value, ...
                        app.VccEditField.Value, ...
                        app.IccEditField.Value, ...
                        app.PCCEditField.Value, ...
                        app.CheckBoxGraficos.Value, ...
                        app.SalvarCheckBox.Value)
                case 'Trifásico'
                     [fig1, fig2, fig3, IMAX, RendMPC, CompRend] = tranformador_trifasico( ...
                        app.SNEditField.Value, ...
                        app.VNATEditField.Value, ...
                        app.VNBTEditField.Value, ...
                        app.V0EditField.Value, ...
                        app.I0EditField.Value, ...
                        app.P0EditField.Value, ...
                        app.VccEditField.Value, ...
                        app.IccEditField.Value, ...
                        app.PCCEditField.Value, ...
                        app.CheckBoxGraficos.Value, ...
                        app.SalvarCheckBox.Value, ...
                        dir)
            end
            
            %%%%%%%%%%%%%%%%%%OUTPUT%%%%%%%%%%%%%%%%%%
            % Figura 1
            %app.EnsaiodeTranformadoresLabel.Text = numstr(fig1(1));
            
            % Figura 2
            app.fig2_1.Text = "$R_{MA}$ = " + num2str(fig2(1));
            app.fig2_2.Text = "$X_{MA}$ = " + num2str(fig2(2));
            app.fig2_3.Text = "$R_{1TS}$ = " + num2str(fig2(3));
            app.fig2_4.Text = "$X_{1S}$ = " + num2str(fig2(4));
            app.fig2_5.Text = "$R_{M}$ = " + num2str(fig2(5));
            app.fig2_6.Text = "$X_{M}$ = " + num2str(fig2(6));
            app.fig2_7.Text = "$R_{1SB}$ = " + num2str(fig2(7));
            app.fig2_8.Text = "$X_{1SB}$ = " + num2str(fig2(8));
            app.fig2_9.Text = "$R_{1STPU}$ = " + num2str(fig2(9));
            app.fig2_10.Text = "$X_{1STPU}$ = " + num2str(fig2(10));
            app.fig2_11.Text = "$R_{MPU}$ = " + num2str(fig2(11));
            app.fig2_12.Text = "$X_{MPU}$ = " + num2str(fig2(12));
            
            % Figura 3
            app.fig3_1.Text = "$R_{1S}$ = " + num2str(fig3(1));
            app.fig3_2.Text = "$X_{1S}$ = " + num2str(fig3(2));
            app.fig3_3.Text = "$R_{1SB}$ = " + num2str(fig3(3));
            app.fig3_4.Text = "$X_{1SB}$ = " + num2str(fig3(4));
            app.fig3_5.Text = "$R_{1STPU}$ = " + num2str(fig3(5));
            app.fig3_6.Text = "$X_{1SPU}$ = " + num2str(fig3(6));
            
            % Rendimento
            app.Carregamento.Text = "$I_{2pu}$ = " + num2str(IMAX);
            app.Rend080.Text = "$\eta_{0.80\%}$ = " + num2str(RendMPC(1));
            app.Rend085.Text = "$\eta_{0.85\%}$ = " + num2str(RendMPC(2));
            app.Rend090.Text = "$\eta_{0.90\%}$ = " + num2str(RendMPC(3));
            app.Rend095.Text = "$\eta_{0.95\%}$ = " + num2str(RendMPC(4));
            app.Rend100.Text = "$\eta_{1.00\%}$ = " + num2str(RendMPC(5));
            
            app.Comp080.Text = "$Comp_{0.80\%}$ = " + num2str(CompRend(1));
            app.Comp085.Text = "$Comp_{0.85\%}$ = " + num2str(CompRend(2));
            app.Comp090.Text = "$Comp_{0.90\%}$ = " + num2str(CompRend(3));
            app.Comp095.Text = "$Comp_{0.95\%}$ = " + num2str(CompRend(4));
            app.Comp100.Text = "$Comp_{1.00\%}$ = " + num2str(CompRend(5));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AjudaButton = uibutton(app.UIFigure, 'push');
            app.AjudaButton.ButtonPushedFcn = createCallbackFcn(app, @AjudaButtonPushed, true);
            app.AjudaButton.Text = 'Ajuda';
            app.CheckBoxGraficos = uicheckbox(app.UIFigure);
            app.CheckBoxGraficos.Text = 'Gerar Gráficos';
            app.CheckBoxGraficos.Value = true;
            app.EnsaiodeTranformadoresLabel = uilabel(app.UIFigure);
            app.EnsaiodeTranformadoresLabel.Text = 'Ensaio de Tranformadores';
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.Title = 'Tipo de transformador';
            app.MonofsicoButton = uiradiobutton(app.TypeButtonGroup);
            app.MonofsicoButton.Text = 'Monofásico';
            app.MonofsicoButton.Value = true;
            app.TrifsicoButton = uiradiobutton(app.TypeButtonGroup);
            app.TrifsicoButton.Text = 'Trifásico';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.SNEditField = uieditfield(app.UIFigure, 'numeric');
            app.S_NKVALabel = uilabel(app.UIFigure);
            app.S_NKVALabel.Text = '$S_N \ [KVA] $';
            app.VNATEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_NATKVLabel = uilabel(app.UIFigure);
            app.V_NATKVLabel.Text = '$V_{NAT} \ [KV] $';
            app.VNBTEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_NBTVLabel = uilabel(app.UIFigure);
            app.V_NBTVLabel.Text = '$V_{NBT} \ [V] $';
            app.V0EditField = uieditfield(app.UIFigure, 'numeric');
            app.V_0VLabel = uilabel(app.UIFigure);
            app.V_0VLabel.Text = '$V_0 \ [V] $';
            app.I0EditField = uieditfield(app.UIFigure, 'numeric');
            app.I_0ALabel = uilabel(app.UIFigure);
            app.I_0ALabel.Text = '$I_0 \ [A] $';
            app.P0EditField = uieditfield(app.UIFigure, 'numeric');
            app.P_0WLabel = uilabel(app.UIFigure);
            app.P_0WLabel.Text = '$P_0 \ [W] $';
            app.VccEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_CCVLabel = uilabel(app.UIFigure);
            app.V_CCVLabel.Text = '$V_{CC} \ [V] $';
            app.IccEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_ccALabel = uilabel(app.UIFigure);
            app.I_ccALabel.Text = '$I_{cc } \ [A] $';
            app.PCCEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_CCWLabel = uilabel(app.UIFigure);
            app.P_CCWLabel.Text = '$P_{CC} \ [W] $';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FigurasTab = uitab(app.TabGroup);
            app.FigurasTab.Title = 'Figuras';
            app.Image = uiimage(app.FigurasTab);
            app.Image.Tooltip = {'Modelo completo do transformador.'};
            app.Image.ImageSource = 'Completo.png';
            app.Image2 = uiimage(app.FigurasTab);
            app.Image2.Tooltip = {'Modelo simplificado 1 do transformador, onde as resistências e reatâncias de dispersão dos'; 'enrolamentos primário e secundário foram consideradas em série.'};
            app.Image2.ImageSource = 'Simplificado_1.png';
            app.Image3 = uiimage(app.FigurasTab);
            app.Image3.Tooltip = {'Modelo simplificado 2 do transformador, onde o ramo de magnetização foi desprezado'; ''; ''};
            app.Image3.ImageSource = 'Simplificado_2.png';
            app.Circuitosimplificado2Label = uilabel(app.FigurasTab);
            app.Circuitosimplificado2Label.Text = '1 -  Circuito simplificado  2';
            app.CircuitocompletoLabel = uilabel(app.FigurasTab);
            app.CircuitocompletoLabel.Text = '2 - Circuito completo';
            app.CircuitoSimplificado2Label = uilabel(app.FigurasTab);
            app.CircuitoSimplificado2Label.Text = {'3 - CircuitoSimplificado 2'; ''};
            app.Figura1Tab = uitab(app.TabGroup);
            app.Figura1Tab.Title = 'Figura 1';
            app.Figura2Tab = uitab(app.TabGroup);
            app.Figura2Tab.Title = 'Figura 2';
            app.GridLayout3 = uigridlayout(app.Figura2Tab);
            app.fig2_1 = uilabel(app.GridLayout3);
            app.fig2_1.Text = '$R_{MA}$ = x';
            app.fig2_2 = uilabel(app.GridLayout3);
            app.fig2_2.Text = '$X_{MA}$ = x';
            app.fig2_3 = uilabel(app.GridLayout3);
            app.fig2_3.Text = '$R_{1TS}$ = x';
            app.fig2_4 = uilabel(app.GridLayout3);
            app.fig2_4.Text = '$X_{1S}$ = x';
            app.fig2_5 = uilabel(app.GridLayout3);
            app.fig2_5.Text = '$R_{M}$ = x';
            app.fig2_6 = uilabel(app.GridLayout3);
            app.fig2_6.Text = '$X_{M}$ = x';
            app.fig2_7 = uilabel(app.GridLayout3);
            app.fig2_7.Text = '$R_{1SB}$ = x';
            app.fig2_8 = uilabel(app.GridLayout3);
            app.fig2_8.Text = '$X_{1SB}$ = x';
            app.fig2_9 = uilabel(app.GridLayout3);
            app.fig2_9.Text = '$R_{1STPU}$ = x';
            app.fig2_10 = uilabel(app.GridLayout3);
            app.fig2_10.Text = '$X_{1STPU}$ = x';
            app.fig2_11 = uilabel(app.GridLayout3);
            app.fig2_11.Text = '$R_{MPU}$ = x';
            app.fig2_12 = uilabel(app.GridLayout3);
            app.fig2_12.Text = '$X_{MPU}$ = x';
            app.Figura3Tab = uitab(app.TabGroup);
            app.Figura3Tab.Title = 'Figura 3';
            app.GridLayout4 = uigridlayout(app.Figura3Tab);
            app.fig3_1 = uilabel(app.GridLayout4);
            app.fig3_1.Text = '$R_{1S}$ = x';
            app.fig3_2 = uilabel(app.GridLayout4);
            app.fig3_2.Text = '$X_{1S}$ = x';
            app.fig3_3 = uilabel(app.GridLayout4);
            app.fig3_3.Text = '$R_{1SB}$ = x';
            app.fig3_4 = uilabel(app.GridLayout4);
            app.fig3_4.Text = '$X_{1SB}$ = x';
            app.fig3_5 = uilabel(app.GridLayout4);
            app.fig3_5.Text = '$R_{1STPU}$ = x';
            app.fig3_6 = uilabel(app.GridLayout4);
            app.fig3_6.Text = '$X_{1SPU}$ = x';
            app.RendimentoTab = uitab(app.TabGroup);
            app.RendimentoTab.Title = 'Rendimento';
            app.GridLayout2 = uigridlayout(app.RendimentoTab);
            app.Rend080 = uilabel(app.GridLayout2);
            app.Rend080.Text = '$\eta_{0.80\%}$ = x';
            app.Comp080 = uilabel(app.GridLayout2);
            app.Comp080.Text = '$Comp_{0.85\%}$ = x';
            app.Rend085 = uilabel(app.GridLayout2);
            app.Rend085.Text = '$\eta_{0.85\%}$ = x';
            app.Comp085 = uilabel(app.GridLayout2);
            app.Comp085.Text = '$Comp_{0.85\%}$ = x';
            app.Rend090 = uilabel(app.GridLayout2);
            app.Rend090.Text = '$\eta_{0.90\%}$ = x';
            app.Comp090 = uilabel(app.GridLayout2);
            app.Comp090.Text = '$Comp_{0.90\%}$ = x';
            app.Rend095 = uilabel(app.GridLayout2);
            app.Rend095.Text = '$\eta_{0.95\%}$ = x';
            app.Comp095 = uilabel(app.GridLayout2);
            app.Comp095.Text = '$Comp_{0.95\%}$ = x';
            app.Comp100 = uilabel(app.GridLayout2);
            app.Comp100.Text = '$Comp_{1.00\%}$ = x';
            app.Rend100 = uilabel(app.GridLayout2);
            app.Rend100.Text = '$\eta_{1.00\%}$ = x';
            app.Carregamento = uilabel(app.GridLayout2);
            app.Carregamento.Text = '$I_{2pu}$ =  x';
            app.SalvarCheckBox = uicheckbox(app.UIFigure);
            app.SalvarCheckBox.Tooltip = {''; ''};
            app.SalvarCheckBox.Text = 'Salvar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformador_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end